classdef SAMD_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea_3            matlab.ui.control.TextArea
        RunPAMButton          matlab.ui.control.Button
        TextArea_2            matlab.ui.control.TextArea
        TextArea              matlab.ui.control.TextArea
        PreprocessDataButton  matlab.ui.control.Button
        RunPCAButton          matlab.ui.control.Button
        PatientDataLabel      matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            methods (Access = private)

            T = readtable('TBI_Full_Dataset for analysis 4-9-26.xlsx');

            app.Data = T;
            app.GOSE = T.GOSE;

            % Optional: show raw data immediately
            app.PatientTable.Data = T;

            % Initial status for preprocessed data
            app.TextArea.Value = 'Data not preprocessed yet ❌';
            app.TextArea.FontColor = [1 0 0]; % Red text for warning
            
        end
function updateScatter(app)

    cla(app.ScatterPlotAxes);

    scatter(app.ScatterPlotAxes, ...
        app.Embedding(:,1), ...
        app.Embedding(:,2), ...
        50, app.ClusterIdx, 'filled');

    hold(app.ScatterPlotAxes, 'on');

    % Highlight outliers
    outliers = app.ClusterIdx == -1;
    scatter(app.ScatterPlotAxes, ...
        app.Embedding(outliers,1), ...
        app.Embedding(outliers,2), ...
        80, 'r', 'LineWidth', 2);

    hold(app.ScatterPlotAxes, 'off');

    % Enable clicking
    dcm = datacursormode(app.UIFigure);
    dcm.Enable = 'on';
    dcm.UpdateFcn = @(~,event) displayPatientData(app, event);

end

function txt = displayPatientData(app, event)

    idx = event.DataIndex;

    patient = app.Data(idx,:);

    % Update table
    app.PatientTable.Data = patient;

    txt = {
        ['Patient #: ', num2str(idx)]
        ['Cluster: ', num2str(app.ClusterIdx(idx))]
        ['GOSE: ', num2str(app.GOSE(idx))]
    };

end

function updateHistogram(app)

    cla(app.HistogramAxes);
    hold(app.HistogramAxes, 'on');

    clusters = unique(app.ClusterIdx);
    clusters(clusters == -1) = []; % remove outliers

    medians = [];

    for c = clusters'
        gose_vals = app.GOSE(app.ClusterIdx == c);

        histogram(app.HistogramAxes, gose_vals, ...
            'DisplayName', ['Cluster ', num2str(c)]);

        medians(end+1) = median(gose_vals);
    end

    legend(app.HistogramAxes);

    % Update median label
    app.MedianLabel.Text = ...
        ['Median GOSE: ', mat2str(medians)];

    hold(app.HistogramAxes, 'off');

end
    end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)


        % Extract features (REMOVE GOSE from features)
        X = table2array(app.Data(:, setdiff(app.Data.Properties.VariableNames, {'GOSE'})));

        % Dimensionality Reduction 
        app.Y_tsne = tsne(X);

        % If UMAP installed
        app.Y_umap = run_umap(X);

        % Default embedding
        app.Embedding = app.Y_tsne;

        % DBSCAN Clustering 
        epsilon = 2;     % tune this more
        minpts = 5;

        app.ClusterIdx = dbscan(X, epsilon, minpts);

        %  Update UI
        updateScatter(app);
        updateHistogram(app);

        % Outlier Alert 
        numOutliers = sum(app.ClusterIdx == -1);

        if numOutliers > 0
        uialert(app.UIFigure, ...
            [num2str(numOutliers), ' outliers detected'], ...
            'DBSCAN Alert');
        end

end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            function EmbeddingSelectorValueChanged(app, event) %Review this

        val = app.EmbeddingSelector.Value;

        if strcmp(val, 't-SNE')
        app.Embedding = app.Y_tsne;
        else
        app.Embedding = app.Y_umap;
        end

        updateScatter(app);

end
        end

        % Button pushed function: PreprocessDataButton
        function PreprocessDataButtonPushed(app, event)
        %% 1. FEATURE SELECTION & COMBINATION

        % % 1a. RENAMING
        % Rename a variable
        data = renamevars(data, {'Tau0h', 'Tau24h'}, {'Tau0H', 'Tau24H'});
        
        % List all biomarkers
        biomarkers = {'ASC', 'Caspase_1', 'IL_18', 'IL_1b', 'Tau', 'GFAP', 'UCHL1', 'NFL', 'pTaut'};
        
        for i = 1:length(biomarkers)
            col0    = [biomarkers{i}, '0H'];
            col24   = [biomarkers{i}, '24H'];
            new_col = [biomarkers{i}, '_First 24h'];
        
            has0  = ismember(col0,  data.Properties.VariableNames);
            has24 = ismember(col24, data.Properties.VariableNames);
        
            % If both 0h and 24h values are present, use the mean
            if has0 && has24
                v0  = data.(col0);
                v24 = data.(col24);
        
                combined = nan(height(data), 1);
                both_present = ~isnan(v0) & ~isnan(v24);
                only0_present = ~isnan(v0) &  isnan(v24);
                only24_present = isnan(v0) & ~isnan(v24);
        
                combined(both_present) = (v0(both_present) + v24(both_present)) / 2;
                combined(only0_present) = v0(only0_present);
                combined(only24_present) = v24(only24_present);
        
                data.(new_col) = combined;
        
            % If only one is present, use that value
            elseif has0 && ~has24
                data.(new_col) = data.(col0);
            elseif ~has0 && has24
                data.(new_col) = data.(col24);
            end
        end
        
        % If none is present, impute using KNN
        
        
        % % 1b. COMBINE AGE IN MONTHS TO AGE IN YEARS
        
        if ismember('AgeInMonths', data.Properties.VariableNames) && ismember('Age_inYears', data.Properties.VariableNames);
            conversion = ~isnan(data.AgeInMonths) & isnan(data.Age_inYears);
            data.Age_inYears(conversion) = data.AgeInMonths(conversion) / 12;
        else 
            disp('Age columns not found.')
        end
        
        
        % % 1c. FEATURE SELECTION 
        
        % Quantitative Clinical Labels
        numeric_clinical = {'Age_inYears', 'Weight', 'Height', 'BMI', 'BSA', ...
                            'GCS', 'HospitalStay_LOS', 'ICU_LOS', ...
                            'PRISMScore', 'ISSScore', 'MarshallScoreAdmission'};
        
        % Biomarkers (Strictly 24h)
        biomarker_24h_vars = strcat(biomarkers, '_First 24h');
        
        % Binary/Qualitative to keep
        binary_vars = {'AbusiveHeadInjuty', 'PolytraumaY_N', 'ICPMonitoringY_N', ...
                       'CSFDiversionY_N', 'MannitolOr3_SalineY_N', 'SeizuresY_N', ...
                       'HypoxicEvent', 'HyperventilationY_N', 'CardiacArrestY_N', ...
                       'IMVY_N', 'CraniectomyY_N', 'HypothermiaY_N', ...
                       'HistoryOfPriorTBIY_N', 'FeverY_N', 'AnalgesicsY_N', ...
                       'Anti_inflammatoryY_N', 'SedativesY_N', 'CTAbnormality'};
        
        qualitative_to_encode = {'Mortality', 'Severity', 'Gender', 'Ethnicity', 'Race'};
        
        %% 2. ONE-HOT ENCODING & BINARY CONVERSION
        % Initialize data_encoded with the quantitative variables
        data_encoded = data(:, [numeric_clinical, biomarker_24h_vars]); 
        
        % Convert Y/N to 1/0
        for i = 1:length(binary_vars)
            var = binary_vars{i};
            if ismember(var, data.Properties.VariableNames)
                % 1. Convert to string and trim any hidden spaces
                clean_data = strtrim(string(data.(var))); 
                
                % 2. Check for 'Y', 'Yes', or '1' to be safe
                is_yes = strcmpi(clean_data, 'Yes') | (clean_data == "1");
                
                data_encoded.(var) = double(is_yes);
            end
        end
        
        % One-Hot Encode (Qualitative to numerical)
        for i = 1:length(qualitative_to_encode)
            var = qualitative_to_encode{i};
            if ismember(var, data.Properties.VariableNames)
                cats       = categorical(data.(var));
                temp_dummy = dummyvar(cats);                    % k columns
                temp_dummy = temp_dummy(:, 1:end-1);            % drop last (reference category)
        
                cat_labels = categories(cats);
                new_names  = strcat(var, '_', string(cat_labels(1:end-1)'));
        
                dummy_table  = array2table(temp_dummy, 'VariableNames', new_names);
                data_encoded = [data_encoded, dummy_table]; 
            else
                fprintf('Warning: qualitative variable "%s" not found — skipped.\n', var);
            end
        end
        
        target_vars = data_encoded.Properties.VariableNames;
        fprintf('Feature matrix ready: %d patients × %d features\n', height(data_encoded), length(target_vars));

        %% 3. IMPUTATION 
        % Use KNN to fill missing biomarkers
        X_raw = table2array(data_encoded); 
        imputed = fillmissing(X_raw, 'knn', 5); 
        
        %% 4. LOG SCALING
        % To handle skewed biomarker distributions
        imputed = log10(imputed + 1);
        
        %% 5. Z-SCORE NORMALIZATION 
        X_zscored = normalize(imputed); 
        
        % Convert back to table for the rest of the app to use
        cleanData = array2table(X_zscored, 'VariableNames', data_encoded.Properties.VariableNames);

        end

        % Callback function: RunPCAButton, UIAxes2
        function RunPCAButtonPushed(app, event)
        % 1. Define specific features (as per your script)
        biomarkers = {'ASC_First 24h','Caspase_1_First 24h','IL_1b_First 24h',...
                  'Tau_First 24h','GFAP_First 24h','UCHL1_First 24h',...
                  'NFL_First 24h','pTaut_First 24h'};
        clinical_req = {'GCS','Weight','Age_inYears'};
        X_input = table2array(app.DataNormalized(:, [biomarkers, clinical_req]));
        [app.Coeff, app.Score, ~, ~, app.Explained] = pca(X_input);
        
        % Update UIAxes2 with the Pareto (Explain Plot)
        cla(app.UIAxes2);
        pareto(app.UIAxes2, app.Explained);
        title(app.UIAxes2, 'PCA Variance Explained');
        
        app.TextArea.Value = 'PCA Complete: Rank Identified ✅'
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Clustering (k = 3)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PCA Variance Explained')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Clustering (k = optimal)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.PatientDataLabel = uilabel(app.UIFigure);
            app.PatientDataLabel.Text = 'Patient Data';
            app.RunPCAButton = uibutton(app.UIFigure, 'push');
            app.RunPCAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.RunPCAButton.Text = 'Run PCA';
            app.PreprocessDataButton = uibutton(app.UIFigure, 'push');
            app.PreprocessDataButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessDataButtonPushed, true);
            app.PreprocessDataButton.Text = 'Preprocess Data';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.RunPAMButton = uibutton(app.UIFigure, 'push');
            app.RunPAMButton.Text = 'Run PAM';
            app.TextArea_3 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAMD_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end